classdef NewMint < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        XButton                   matlab.ui.control.Button
        EditField                 matlab.ui.control.NumericEditField
        StepsSlider               matlab.ui.control.Slider
        StepsSliderLabel          matlab.ui.control.Label
        SelectSumTypeButtonGroup  matlab.ui.container.ButtonGroup
        Button4                   matlab.ui.control.ToggleButton
        Button5                   matlab.ui.control.ToggleButton
        Button3                   matlab.ui.control.ToggleButton
        Button2                   matlab.ui.control.ToggleButton
        Button                    matlab.ui.control.ToggleButton
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        gxEditField               matlab.ui.control.EditField
        gxEditFieldLabel          matlab.ui.control.Label
        fxEditField               matlab.ui.control.EditField
        fxEditFieldLabel          matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        SumErrorLabel             matlab.ui.control.Label
        SumLabel                  matlab.ui.control.Label
        IntegralLabel             matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % Most up to date function variables -- direct user input
        newFx;
        newGx;
        % Slightly outdated function variables -- updated after anim.
        currentFx = 0;
        currentGx = 0;
        
        % Pause reading user input when actions happen
        pauseInputs = false;

        % # steps for numeric integration
        steps = 0;

    end
    
    methods (Access = private)
        
        function shouldRedraw(app)

            if (app.newFx ~= app.currentFx) && (app.pauseInputs == false)
                redraw(app, 'f')
                app.currentFx = app.newFx;
            end
        end
        
        function redraw(app, currentFunction)
            app.pauseInputs = true;
            if currentFunction == 'f'
                for n = 0:0.05:1
                    intFn = n^2*(3-2*n);

                    fnToPlot = (1 - intFn) * app.currentFx + intFn * app.newFx;
                    fplot(app.UIAxes, fnToPlot)
                    app.UIAxes.XLim = [-5, 5];
                    app.UIAxes.YLim = [-5, 5];
                    
                    pause(0.001);
                end
            else
            % Redraw g with f constant
            end
            app.pauseInputs = false;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: fxEditField
        function fxEditFieldValueChanging(app, event)
            changingValue = event.Value;
            try
                % Color Blue
                app.newFx = str2sym(changingValue);
                shouldRedraw(app)
            catch
                % Color Gray
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {331, 331};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.fxEditFieldLabel = uilabel(app.LeftPanel);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.LeftPanel, 'text');
            app.fxEditField.ValueChangingFcn = createCallbackFcn(app, @fxEditFieldValueChanging, true);
            app.fxEditField.Placeholder = 'Enter f(x)';
            app.gxEditFieldLabel = uilabel(app.LeftPanel);
            app.gxEditFieldLabel.Text = 'g(x)';
            app.gxEditField = uieditfield(app.LeftPanel, 'text');
            app.gxEditField.Placeholder = 'Enter g(x)';
            app.aEditFieldLabel = uilabel(app.LeftPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SelectSumTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.SelectSumTypeButtonGroup.Title = 'Select Sum Type';
            app.Button = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.Button3.Text = 'Button3';
            app.Button5 = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.Button5.Text = 'Button4';
            app.Button4 = uitogglebutton(app.SelectSumTypeButtonGroup);
            app.Button4.Text = 'Button5';
            app.StepsSliderLabel = uilabel(app.LeftPanel);
            app.StepsSliderLabel.Text = '# Steps';
            app.StepsSlider = uislider(app.LeftPanel);
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.XButton = uibutton(app.LeftPanel, 'push');
            app.XButton.Text = 'X';
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'GRAPH')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IntegralLabel = uilabel(app.RightPanel);
            app.IntegralLabel.Text = 'Integral';
            app.SumLabel = uilabel(app.RightPanel);
            app.SumLabel.Text = '___ Sum';
            app.SumErrorLabel = uilabel(app.RightPanel);
            app.SumErrorLabel.Text = 'Sum Error';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewMint

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end